classdef Convolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DescreteConvolutionUIFigure    matlab.ui.Figure
        sigplot1                       matlab.ui.control.UIAxes
        ExitButton                     matlab.ui.control.Button
        MainmenuButton                 matlab.ui.control.Button
        ConvolveButton                 matlab.ui.control.Button
        sigplot2                       matlab.ui.control.UIAxes
        convplot                       matlab.ui.control.UIAxes
        aniplot                        matlab.ui.control.UIAxes
        FirstSignalPanel               matlab.ui.container.Panel
        AmplitudeEditFieldLabel        matlab.ui.control.Label
        ampl_1                         matlab.ui.control.NumericEditField
        RepetionnoEditFieldLabel       matlab.ui.control.Label
        repno_1                        matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel       matlab.ui.control.Label
        width_1                        matlab.ui.control.NumericEditField
        ZeropaddingEditFieldLabel      matlab.ui.control.Label
        zpad_1                         matlab.ui.control.NumericEditField
        CircularshiftEditFieldLabel    matlab.ui.control.Label
        cshift_1                       matlab.ui.control.NumericEditField
        ChoosepulseDropDownLabel_3     matlab.ui.control.Label
        pulse_type_1                   matlab.ui.control.DropDown
        SecondSignalPanel              matlab.ui.container.Panel
        ChoosepulseDropDownLabel_2     matlab.ui.control.Label
        pulse_type_2                   matlab.ui.control.DropDown
        AmplitudeEditFieldLabel_2      matlab.ui.control.Label
        ampl_2                         matlab.ui.control.NumericEditField
        RepetionnoEditFieldLabel_2     matlab.ui.control.Label
        repno_2                        matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel_2     matlab.ui.control.Label
        width_2                        matlab.ui.control.NumericEditField
        ZeropaddingEditFieldLabel_2    matlab.ui.control.Label
        zpad_2                         matlab.ui.control.NumericEditField
        CircularshiftEditFieldLabel_2  matlab.ui.control.Label
        cshift_2                       matlab.ui.control.NumericEditField
        DrawButton                     matlab.ui.control.Button
        AnimationSwitchLabel           matlab.ui.control.Label
        AnimationSwitch                matlab.ui.control.Switch
        Switch                         matlab.ui.control.Switch
    end

    
    methods (Access = private)
        % this function aquires all the data neded for calculations
        function [ampl1,repno1,width1,zpad1,cshift1,pulsetype1,ampl2,repno2,width2,zpad2,cshift2,pulsetype2] = getdata(app)
            ampl1=app.ampl_1.Value;%amlitude of signal 1
            repno1=app.repno_1.Value;% number of repetions of the basic signal
            width1=app.width_1.Value;% number of bins in signal1
            zpad1=app.zpad_1.Value;% the number of zeros to be padded in the signal
            cshift1=app.cshift_1.Value;% the amount of circular shift
            pulsetype1=app.pulse_type_1.Value;% the type of the input pulse ()
            
            ampl2=app.ampl_2.Value;%amlitude of signal 1
            repno2=app.repno_2.Value;% number of repetions of the basic signal
            width2=app.width_2.Value;% number of bins in signal2
            zpad2=app.zpad_2.Value;% the number of zeros to be padded in the signal
            cshift2=app.cshift_2.Value;% the amount of circular shift
            pulsetype2=app.pulse_type_2.Value;% the type of the input pulse ()
        end
        %------------------------------------------------------------------------------------------------------------------------------------------------
        function [signal1,signal2] = draw(app) % this function draw both signals and send return two vectors containing them
            [ampl1,repno1,width1,zpad1,cshift1,pulsetype1,ampl2,repno2,width2,zpad2,cshift2,pulsetype2] = getdata(app);
            
            % identifying pulses
            switch pulsetype1
                case '1' %rect pulse
                    t=0:width1;
                    signal1=ampl1*rectpuls(t,2*width1);
                case '2' %triangular pulse
                    t=-width1/2:width1/2;
                    signal1=ampl1*tripuls(t,width1);
                case '3' %sin pulse
                    t=0:width1;
                    signal1=ampl1*sin(t*(pi/width1));
            end
            
            
            signal1=[signal1 zeros(1,zpad1)]; % zero padding the signal
            signal1=circshift(signal1,cshift1);%circular shift
            
            temp1=signal1;
            for i=1:repno1-1 %making multiple repeats of the signal
                temp1=[temp1 signal1];
            end
            signal1=temp1;
            
            %Second signal
            
            switch pulsetype2
                case '1' %rect pulse
                    t=0:width2;
                    signal2=ampl2*rectpuls(t,2*width2);
                case '2' %triangular pulse
                    t=-width2/2:width2/2;
                    signal2=ampl2*tripuls(t,width2);
                case '3' %si.n pulse
                    t=0:width2;
                    signal2=ampl2*sin(t*(pi/width2));
            end


            signal2=[signal2 zeros(1,zpad2)];% zero padding the signal
            signal2=circshift(signal2,cshift2);%circular shift
            
            temp2=signal2;
            for i=1:repno2-1%making multiple repeats of the signal
                temp2=[temp2 signal2];
            end
            signal2=temp2;
            
            if (app.Switch.Value=='1') % to draw in signal1 plot or stem
                t1=0:length(signal1)-1;
                stem(app.sigplot1,t1,signal1);
                %setting axis limits
                app.sigplot1.XLim=[-1  length(signal1)];
                app.sigplot1.YLim=[1.5*min(signal1)  1.5*max(signal1)];
                
                t2=0:length(signal2)-1;
                stem(app.sigplot2,t2,signal2,'r');
                %setting axis limits
                app.sigplot2.XLim=[-1  length(signal2)];
                app.sigplot2.YLim=[1.5*min(signal2)  1.5*max(signal2)];
                
            else
                t1=0:length(signal1)-1;
                plot(app.sigplot1,t1,signal1);
                
                app.sigplot1.XLim=[-1  length(signal1)];
                app.sigplot1.YLim=[1.5*min(signal1)  1.5*max(signal1)];
                
                
                t2=0:length(signal2)-1;
                plot(app.sigplot2,t2,signal2,'r');
                
                app.sigplot2.XLim=[-1  length(signal2)];
                app.sigplot2.YLim=[1.5*min(signal2)  1.5*max(signal2)];
                
            end
        end
        
        function  convresult=drawconv(app)
            
            [signal1,signal2] = draw(app);
            
            convresult=conv(signal1,signal2);
            tconv=0:length(convresult)-1;
            
            
            if (app.AnimationSwitch.Value=='0')%Draw without animation
                
                if (app.Switch.Value=='1')
                    stem(app.convplot,tconv,convresult,'g');
                    
                    app.convplot.XLim=[-1  length(convresult)];
                    app.convplot.YLim=[1.5*min(convresult)  1.5*max(convresult)];
                else
                    plot(app.convplot,tconv,convresult,'g');
                    
                    app.convplot.XLim=[-1  length(convresult)];
                    app.convplot.YLim=[1.5*min(convresult)  1.5*max(convresult)];
                end
            else %draw with animation
                
                convresult=conv(signal1,signal2);%calculate the convolution result previously before plotting
                %setting axis limits
                app.convplot.YLim=[1.5*min(convresult)  1.5*max(convresult)];
                app.convplot.XLim=[0  length(convresult)];
                
                width1=length(signal1);
                width2=length(signal2);
                signal1=fliplr(signal1);
                
                signal1 =[signal1 zeros(1,width1+width2-1)];%making the signals at the same size to be plotted correctly
                signal2 =[zeros(1,width1-1) signal2 zeros(1,width1)];
                sig1i=signal1;% the signal that will be shifted every time in the loop to let signal 1 remain intact
                
                convi=zeros(1,width1+width2);% the real time conolution result that will be updated at each iteration
                t=-width1+1:width1+width2-1;
                
                app.aniplot.YLim=[1.5*min(min(signal1),min(signal2)) 1.5*max(max(signal1),max(signal2))];
                
                for  i=1:width1+width2-1 % loop until the
                    
                    if (app.Switch.Value=='1')
                        stem(app.aniplot,t,signal1,'b');
                        hold(app.aniplot,'on')
                        stem(app.aniplot,t,signal2,'r');
                        hold(app.aniplot,'off')
                        
                    else
                        plot(app.aniplot,t,signal1,'b');
                        hold(app.aniplot,'on')
                        plot(app.aniplot,t,signal2,'r');
                        hold(app.aniplot,'off')
                    end
                    
                    signal1 = [zeros(1,i) sig1i(1:end-i)];%shift the signal by step 1 every iteration
                   convi(i)=convresult(i);  % this line assigns the value of convolution to the signal being drawn in real time
                   
                    
                    if (app.Switch.Value=='1')
                        stem (app.convplot,0:width1+width2-1,convi,'g')
                    else
                        plot (app.convplot,0:width1+width2-1,convi,'g')
                    end
                    pause(2.5/width1);%in order to make the signal move in constant speed
                    
                end
                
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; % to clear all errors ,if any , in the previous run
        end

        % Button pushed function: MainmenuButton
        function MainmenuButtonPushed(app, event)
            choice=menu('all the cahanges you made will get lost !','OK','Cancel');
            
            if choice==1
                app.delete;
                Mainmenue;
            end
            
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            choice=menu('Are you sure you want to exit ?','yes','No');
            if choice==1
                app.delete;
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            
            draw(app);
        end

        % Button pushed function: ConvolveButton
        function ConvolveButtonPushed(app, event)
            drawconv(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DescreteConvolutionUIFigure = uifigure;
            app.DescreteConvolutionUIFigure.Name = 'Descrete Convolution';
            app.sigplot1 = uiaxes(app.DescreteConvolutionUIFigure);
            title(app.sigplot1, 'First Signal')
            xlabel(app.sigplot1, 't[n]')
            ylabel(app.sigplot1, 'ampl')
            app.ExitButton = uibutton(app.DescreteConvolutionUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.MainmenuButton = uibutton(app.DescreteConvolutionUIFigure, 'push');
            app.MainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainmenuButtonPushed, true);
            app.MainmenuButton.Text = 'Main menu';
            app.ConvolveButton = uibutton(app.DescreteConvolutionUIFigure, 'push');
            app.ConvolveButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolveButtonPushed, true);
            app.ConvolveButton.Text = 'Convolve';
            app.sigplot2 = uiaxes(app.DescreteConvolutionUIFigure);
            title(app.sigplot2, 'Second Signal')
            xlabel(app.sigplot2, 't[n]')
            ylabel(app.sigplot2, 'ampl')
            app.convplot = uiaxes(app.DescreteConvolutionUIFigure);
            title(app.convplot, 'Convolution Result')
            xlabel(app.convplot, 't[n]')
            ylabel(app.convplot, 'ampl')
            app.aniplot = uiaxes(app.DescreteConvolutionUIFigure);
            title(app.aniplot, 'Animation')
            xlabel(app.aniplot, 't[n]')
            ylabel(app.aniplot, 'ampl')
            app.FirstSignalPanel = uipanel(app.DescreteConvolutionUIFigure);
            app.FirstSignalPanel.Title = 'First Signal';
            app.AmplitudeEditFieldLabel = uilabel(app.FirstSignalPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.ampl_1 = uieditfield(app.FirstSignalPanel, 'numeric');
            app.ampl_1.Value = 1;
            app.RepetionnoEditFieldLabel = uilabel(app.FirstSignalPanel);
            app.RepetionnoEditFieldLabel.Text = 'Repetion no.';
            app.repno_1 = uieditfield(app.FirstSignalPanel, 'numeric');
            app.repno_1.LowerLimitInclusive = 'off';
            app.repno_1.Limits = [0 Inf];
            app.repno_1.RoundFractionalValues = 'on';
            app.repno_1.Value = 2;
            app.PulseWidthEditFieldLabel = uilabel(app.FirstSignalPanel);
            app.PulseWidthEditFieldLabel.Text = 'Pulse Width';
            app.width_1 = uieditfield(app.FirstSignalPanel, 'numeric');
            app.width_1.LowerLimitInclusive = 'off';
            app.width_1.Limits = [0 Inf];
            app.width_1.RoundFractionalValues = 'on';
            app.width_1.Value = 20;
            app.ZeropaddingEditFieldLabel = uilabel(app.FirstSignalPanel);
            app.ZeropaddingEditFieldLabel.Text = 'Zero padding';
            app.zpad_1 = uieditfield(app.FirstSignalPanel, 'numeric');
            app.zpad_1.Limits = [0 Inf];
            app.zpad_1.RoundFractionalValues = 'on';
            app.zpad_1.ValueDisplayFormat = '%.0f';
            app.zpad_1.Value = 5;
            app.CircularshiftEditFieldLabel = uilabel(app.FirstSignalPanel);
            app.CircularshiftEditFieldLabel.Text = 'Circular shift';
            app.cshift_1 = uieditfield(app.FirstSignalPanel, 'numeric');
            app.cshift_1.RoundFractionalValues = 'on';
            app.ChoosepulseDropDownLabel_3 = uilabel(app.FirstSignalPanel);
            app.ChoosepulseDropDownLabel_3.Text = 'Choose pulse';
            app.pulse_type_1 = uidropdown(app.FirstSignalPanel);
            app.pulse_type_1.Items = {'Rectangular', 'Triangular', 'Sin'};
            app.pulse_type_1.ItemsData = {'1', '2', '3'};
            app.pulse_type_1.Value = '3';
            app.SecondSignalPanel = uipanel(app.DescreteConvolutionUIFigure);
            app.SecondSignalPanel.Title = 'Second Signal';
            app.ChoosepulseDropDownLabel_2 = uilabel(app.SecondSignalPanel);
            app.ChoosepulseDropDownLabel_2.Text = 'Choose pulse';
            app.pulse_type_2 = uidropdown(app.SecondSignalPanel);
            app.pulse_type_2.Items = {'Rectangular', 'Triangular', 'Sin'};
            app.pulse_type_2.ItemsData = {'1', '2', '3'};
            app.pulse_type_2.Value = '2';
            app.AmplitudeEditFieldLabel_2 = uilabel(app.SecondSignalPanel);
            app.AmplitudeEditFieldLabel_2.Text = 'Amplitude';
            app.ampl_2 = uieditfield(app.SecondSignalPanel, 'numeric');
            app.ampl_2.Value = 3;
            app.RepetionnoEditFieldLabel_2 = uilabel(app.SecondSignalPanel);
            app.RepetionnoEditFieldLabel_2.Text = 'Repetion no.';
            app.repno_2 = uieditfield(app.SecondSignalPanel, 'numeric');
            app.repno_2.LowerLimitInclusive = 'off';
            app.repno_2.Limits = [0 Inf];
            app.repno_2.RoundFractionalValues = 'on';
            app.repno_2.Value = 3;
            app.PulseWidthEditFieldLabel_2 = uilabel(app.SecondSignalPanel);
            app.PulseWidthEditFieldLabel_2.Text = 'Pulse Width';
            app.width_2 = uieditfield(app.SecondSignalPanel, 'numeric');
            app.width_2.LowerLimitInclusive = 'off';
            app.width_2.Limits = [0 Inf];
            app.width_2.RoundFractionalValues = 'on';
            app.width_2.Value = 15;
            app.ZeropaddingEditFieldLabel_2 = uilabel(app.SecondSignalPanel);
            app.ZeropaddingEditFieldLabel_2.Text = 'Zero padding';
            app.zpad_2 = uieditfield(app.SecondSignalPanel, 'numeric');
            app.zpad_2.Limits = [0 Inf];
            app.zpad_2.RoundFractionalValues = 'on';
            app.zpad_2.ValueDisplayFormat = '%.0f';
            app.zpad_2.Value = 10;
            app.CircularshiftEditFieldLabel_2 = uilabel(app.SecondSignalPanel);
            app.CircularshiftEditFieldLabel_2.Text = 'Circular shift';
            app.cshift_2 = uieditfield(app.SecondSignalPanel, 'numeric');
            app.cshift_2.RoundFractionalValues = 'on';
            app.DrawButton = uibutton(app.DescreteConvolutionUIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.AnimationSwitchLabel = uilabel(app.DescreteConvolutionUIFigure);
            app.AnimationSwitchLabel.Text = 'Animation';
            app.AnimationSwitch = uiswitch(app.DescreteConvolutionUIFigure, 'slider');
            app.AnimationSwitch.ItemsData = {'0', '1'};
            app.AnimationSwitch.Value = '0';
            app.Switch = uiswitch(app.DescreteConvolutionUIFigure, 'slider');
            app.Switch.Items = {'Stem', 'plot'};
            app.Switch.ItemsData = {'1', '0'};
            app.Switch.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Convolution

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DescreteConvolutionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DescreteConvolutionUIFigure)
        end
    end
end