classdef FIR_Filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIR_filter_design      matlab.ui.Figure
        MainmenuButton         matlab.ui.control.Button
        ExitButton             matlab.ui.control.Button
        DesiredfilterresponselengthEditFieldLabel  matlab.ui.control.Label
        Desired_L              matlab.ui.control.NumericEditField
        DesignedfilterresponselengthEditFieldLabel  matlab.ui.control.Label
        Designed_N             matlab.ui.control.NumericEditField
        MagnituderesponsedisplayDropDownLabel  matlab.ui.control.Label
        Mag_display            matlab.ui.control.DropDown
        Generate               matlab.ui.control.Button
        LSErrorEditFieldLabel  matlab.ui.control.Label
        LS_Error               matlab.ui.control.NumericEditField
        CutoffFrequencyLabel   matlab.ui.control.Label
        omega_cutoff           matlab.ui.control.NumericEditField
        FIRfilterdesignLabel   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [L,N,cutoff,display] = getdata(app)
            L=app.Desired_L.Value;%the desigred length of the filter
            N=app.Designed_N.Value;%the designed length of the filter
            cutoff=app.omega_cutoff.Value;% the cutoff frequency of the low pass filter
            display=app.Mag_display.Value;
        end
        
        function  generate_filter(app)
            [L,N,cutoff,display] = getdata(app);
            
            if (rem(L,2)==0)
                L=L+1;
                fig= app.FIR_filter_design;
                uialert(fig,'Desired filter must be odd','Warning','Icon','warning');
                app.Desired_L.Value=L;
            end
            if (rem(N,2)==0)
                N=N-1;
                fig= app.FIR_filter_design;
                uialert(fig,'Designed filter must be odd','Warning','Icon','warning');
                app.Designed_N.Value=N;
            end


            spectrum=linspace(0,pi,L);%generate an array of size L with values equaly spaced from 0 to pi
            
                        desired_H=zeros(1,L);
            
                        for i=1:L
                            if (spectrum(i)<= cutoff*pi)
                                desired_H(i)=1;
                            else
                                desired_H(i)=0;
                            end
                        end


            range=(N-1)/2;
            
            F=zeros(L,1+range);% this is the coeffiecnt matrix that contains the coefficients of the impulse response
            
            for i=1:L
                F(i,:)=[1,2*cos((1:range)*spectrum(i))];
            end
            
            %calculate the impulse response in the time domain
            time_impulse = F\desired_H';%we transpose the row vector into a column vector for the validity of the the matrix multiplication
            
            time_impulse =time_impulse'; % the product of the matrix multiplication is a column vector that corresponds to the values of the impulse response corresponding to the spectrum arry values
            % we return it back to row vector so that we can draw it properly
          
            fliped_time_impulse=fliplr(time_impulse);
            
            full_time_respnse=[fliped_time_impulse(1:length(time_impulse)-1) ,time_impulse];%because the function is even symmetric and has odd samples, and we have got only
            
            designed_H=freqz(full_time_respnse,1,spectrum);% get the response in the frequency domain and draw it against spectrum
            
            error=abs(designed_H)-abs(desired_H);
            
            LS_error=error*error';
            app.LS_Error.Value=LS_error;
            
            switch display
                case '0' %linear scale
                    
                    datacursormode on;
                    plot(spectrum,abs(designed_H))
                    title('FIR filter Linear scale');
                    xlabel('Frequency (\omega)')
                    ylabel('Ampl')
                    hold on
                    plot(spectrum,abs(desired_H),'g')
                    hold off  % so that if he re entered new values it clears the old filters
                    
                case '1'%log scale
                    
                    datacursormode on;
                    log_designed_H=20*log10(abs(designed_H));
                    log_desired_H=20*log10(abs(desired_H));
                    
                    plot(spectrum,log_designed_H)
                    title('FIR filter log scale');
                    xlabel('Frequency (\omega)')
                    ylabel('Ampl (Db)')
                    hold on
                    plot(spectrum,log_desired_H,'g')
                    hold off  % so that if he re entered new values it clears the old filters
            end
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: MainmenuButton
        function MainmenuButtonPushed(app, event)
            choice=menu('all the cahanges you made will get lost !','OK','Cancel');
            if choice==1
                app.delete;
                Mainmenue;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            choice=menu('Are you sure you want to exit ?','yes','No');
            if choice==1
                app.delete;
            end
        end

        % Value changed function: Desired_L
        function Desired_LValueChanged(app, event)
            value = app.Desired_L.Value;
            app.Designed_N.Limits=[0,value];
        end

        % Button pushed function: Generate
        function GenerateButtonPushed(app, event)
            generate_filter(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIR_filter_design = uifigure;
            app.FIR_filter_design.Name = 'UI Figure';
            app.MainmenuButton = uibutton(app.FIR_filter_design, 'push');
            app.MainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainmenuButtonPushed, true);
            app.MainmenuButton.Text = 'Main menu';
            app.ExitButton = uibutton(app.FIR_filter_design, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.DesiredfilterresponselengthEditFieldLabel = uilabel(app.FIR_filter_design);
            app.DesiredfilterresponselengthEditFieldLabel.Text = 'Desired filter response length';
            app.Desired_L = uieditfield(app.FIR_filter_design, 'numeric');
            app.Desired_L.Limits = [0 Inf];
            app.Desired_L.ValueChangedFcn = createCallbackFcn(app, @Desired_LValueChanged, true);
            app.Desired_L.Value = 501;
            app.DesignedfilterresponselengthEditFieldLabel = uilabel(app.FIR_filter_design);
            app.DesignedfilterresponselengthEditFieldLabel.Text = 'Designed filter response length';
            app.Designed_N = uieditfield(app.FIR_filter_design, 'numeric');
            app.Designed_N.Limits = [0 501];
            app.Designed_N.Value = 401;
            app.MagnituderesponsedisplayDropDownLabel = uilabel(app.FIR_filter_design);
            app.MagnituderesponsedisplayDropDownLabel.Text = 'Magnitude response display';
            app.Mag_display = uidropdown(app.FIR_filter_design);
            app.Mag_display.Items = {'Linear scale', 'Log scale'};
            app.Mag_display.ItemsData = {'0', '1'};
            app.Mag_display.Value = '0';
            app.Generate = uibutton(app.FIR_filter_design, 'push');
            app.Generate.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.Generate.Text = 'Generate Filter';
            app.LSErrorEditFieldLabel = uilabel(app.FIR_filter_design);
            app.LSErrorEditFieldLabel.Text = 'LS Error';
            app.LS_Error = uieditfield(app.FIR_filter_design, 'numeric');
            app.LS_Error.Editable = 'off';
            app.CutoffFrequencyLabel = uilabel(app.FIR_filter_design);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency ';
            app.omega_cutoff = uieditfield(app.FIR_filter_design, 'numeric');
            app.omega_cutoff.UpperLimitInclusive = 'off';
            app.omega_cutoff.Limits = [0 10];
            app.omega_cutoff.Value = 0.25;
            app.FIRfilterdesignLabel = uilabel(app.FIR_filter_design);
            app.FIRfilterdesignLabel.Text = 'FIR filter design';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FIR_Filter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIR_filter_design)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIR_filter_design)
        end
    end
end