classdef Spectrum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrumAnalyzerUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ComputeButton_main              matlab.ui.control.Button
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequency_main          matlab.ui.control.NumericEditField
        WindowlengthEditFieldLabel      matlab.ui.control.Label
        Windowlength_main               matlab.ui.control.NumericEditField
        SignalfrequencyEditFieldLabel   matlab.ui.control.Label
        Signalfrequency_main            matlab.ui.control.NumericEditField
        InputFileEditFieldLabel         matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        BrowseButton_main               matlab.ui.control.Button
        SignalTypeDropDownLabel         matlab.ui.control.Label
        SignalType_main                 matlab.ui.control.DropDown
        NDFTDropDownLabel               matlab.ui.control.Label
        NDFT_main                       matlab.ui.control.DropDown
        DisplayoptionsDropDownLabel     matlab.ui.control.Label
        Displayoptions_main             matlab.ui.control.DropDown
        WindowTypeDropDownLabel         matlab.ui.control.Label
        WindowType_main                 matlab.ui.control.DropDown
        stem_plot_main                  matlab.ui.control.Switch
        ComparisonTab                   matlab.ui.container.Tab
        ComputeButton_2                 matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        GeneralsittingsPanel            matlab.ui.container.Panel
        SamplingFrequencyEditField_2Label  matlab.ui.control.Label
        SamplingFrequencyEditField_calculation  matlab.ui.control.NumericEditField
        SignalfrequencyEditField_2Label  matlab.ui.control.Label
        SignalfrequencyEditField_calculation  matlab.ui.control.NumericEditField
        SignalTypeDropDown_2Label       matlab.ui.control.Label
        SignalTypeDropDown_calculation  matlab.ui.control.DropDown
        DisplayoptionsDropDown_2Label   matlab.ui.control.Label
        Displayoptions_comparison       matlab.ui.control.DropDown
        Window1Panel                    matlab.ui.container.Panel
        NDFT1DropDownLabel              matlab.ui.control.Label
        NDFT1DropDown                   matlab.ui.control.DropDown
        Window1TypeDropDownLabel        matlab.ui.control.Label
        Window1TypeDropDown             matlab.ui.control.DropDown
        Window1lengthEditFieldLabel     matlab.ui.control.Label
        Window1lengthEditField          matlab.ui.control.NumericEditField
        Window2Panel                    matlab.ui.container.Panel
        Window2lengthEditFieldLabel     matlab.ui.control.Label
        Window2lengthEditField          matlab.ui.control.NumericEditField
        Window2TypeDropDownLabel        matlab.ui.control.Label
        Window2TypeDropDown             matlab.ui.control.DropDown
        NDFT2DropDownLabel              matlab.ui.control.Label
        NDFT2DropDown                   matlab.ui.control.DropDown
        CalculationTab                  matlab.ui.container.Tab
        compute_calculation             matlab.ui.control.Button
        InputFileEditField_2Label       matlab.ui.control.Label
        InputFile_calculation           matlab.ui.control.EditField
        BrowseButton_calculation        matlab.ui.control.Button
        GeneratesignalPanel             matlab.ui.container.Panel
        DCcomponentEditFieldLabel       matlab.ui.control.Label
        DC_component_calculation        matlab.ui.control.NumericEditField
        SignalFrequencyLabel            matlab.ui.control.Label
        SignalFrequnecy_calculation     matlab.ui.control.NumericEditField
        SamplingFrequencyEditField_3Label  matlab.ui.control.Label
        SamplingFrequency_calculation   matlab.ui.control.NumericEditField
        SignalTypeDropDown_3Label       matlab.ui.control.Label
        SignalType_calculation          matlab.ui.control.DropDown
        CommonsittingsPanel             matlab.ui.container.Panel
        WindowTypeLabel                 matlab.ui.control.Label
        WindowType_calculation          matlab.ui.control.DropDown
        NDFTLabel                       matlab.ui.control.Label
        NDFT_calculation                matlab.ui.control.DropDown
        WindowlengthLabel               matlab.ui.control.Label
        Windowlength_calculation        matlab.ui.control.NumericEditField
        SpanPanel                       matlab.ui.container.Panel
        BeginingHzLabel                 matlab.ui.control.Label
        Begining_calculation            matlab.ui.control.NumericEditField
        EndHzLabel                      matlab.ui.control.Label
        End_calculation                 matlab.ui.control.NumericEditField
        Reset_span                      matlab.ui.control.Button
        input_choice                    matlab.ui.control.Switch
        AudioinfoPanel                  matlab.ui.container.Panel
        SamplingFrequencyEditField_3Label_2  matlab.ui.control.Label
        SamplingFrequency_calculation_input  matlab.ui.control.NumericEditField
        SignaldurationLabel             matlab.ui.control.Label
        SignalLength_calculation_input  matlab.ui.control.NumericEditField
        stem_plot_calculation           matlab.ui.control.Switch
        DisplayoptionsDropDown_4Label   matlab.ui.control.Label
        Displayoptions_calc             matlab.ui.control.DropDown
        signalInfoPanel                 matlab.ui.container.Panel
        PeakvalueLabel                  matlab.ui.control.Label
        Value_peak                      matlab.ui.control.NumericEditField
        PowerValueEditFieldLabel        matlab.ui.control.Label
        PowerValueEditField             matlab.ui.control.NumericEditField
        RMSEditFieldLabel               matlab.ui.control.Label
        RMS                             matlab.ui.control.NumericEditField
        Lamp                            matlab.ui.control.Lamp
        DCbiasEditFieldLabel            matlab.ui.control.Label
        DCbias                          matlab.ui.control.NumericEditField
        RBWLabel                        matlab.ui.control.Label
        RBW                             matlab.ui.control.NumericEditField
        MainmenuButton                  matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end


    methods (Access = private)
        %this function aqures all needed data for main tab
        function [F,Fs,widlen,widtype,NDFT,sigtype,input,display]= get_main_data(app)
            
            F=app.Signalfrequency_main.Value;% the signal frequency
            
            Fs=app.SamplingFrequency_main.Value;% the sampling frequency
            
            widlen=app.Windowlength_main.Value;% the window length
            
            widtype=app.WindowType_main.Value;%the type of the window 1->rect 2->triang 3->Hannig 4->hamming
            
            NDFT=app.NDFT_main.Value;% NDFt (does not work well if the number of samples is less thean the DFT)
            
            sigtype=app.SignalType_main.Value;%the signal type
            
            input=app.InputFileEditField.Value;% the path of the input file ("" if was not entred)
            
            display=app.Displayoptions_main.Value;%teh type of display
            
        end
        %-----------------------------------------------------------------------------------------------------------
        %this function aqures all needed data for the comparison mode
        function [F,Fs ,widlen1 ,widtype1 ,NDFT1 ,widlen2, widtype2 ,NDFT2,sigtype]= get_comp_data(app)
            
            F=app.SignalfrequencyEditField_calculation.Value;% the signal frequency
            
            Fs=app.SamplingFrequencyEditField_calculation.Value;% the sampling frequency
            
            widlen1=app.Window1lengthEditField.Value;% the window length
            
            widtype1=app.Window1TypeDropDown.Value;%the type of the window 1->rect 2->triang 3->Hannig 4->hamming
            
            NDFT1=app.NDFT1DropDown.Value;% NDFt (does not work well if the number of samples is less thean the DFT)
            
            widlen2=app.Window2lengthEditField.Value;% the window length
            
            widtype2=app.Window2TypeDropDown.Value;%the type of the window 1->rect 2->triang 3->Hannig 4->hamming
            
            NDFT2=app.NDFT2DropDown.Value;% NDFt (does not work well if the number of samples is less thean the DFT)
            
            sigtype=app.SignalTypeDropDown_calculation.Value;%the signal type
            
        end
        
        %----------------------------------------------------------------------------
        %this function computes the signal and n to be plotted
        function [Fs,n,signal] = compute_windowed_signal(app)
            
            [F,Fs,widlen,widtype,~,sigtype,input,~]= get_main_data(app);
            
            n=0:1/Fs:widlen;
            
            sample_rate=Fs;% just to make sure that every thing goes well if the user did not enter a path of the input file
            
            switch sigtype % this case is
                case '1'
                    signal=sin(2*pi*F.*n);
                case '2'
                    signal=sinc(2*F.*n);
                case '3'
                    signal=square(2*pi*F.*n);
                case '4'
                    if(input==' ')
                        signal=sin(2*pi*F.*n);
                        f = app.SpectrumAnalyzerUIFigure;
                        uialert(f,'File not found','Invalid File');
                        
                    else
                        [signal , sample_rate]= audioread(input);
                        Fs=sample_rate;% we by default take the sample rate of the signal
                        duration=floor(length(signal)/Fs);
                        n=0:1/Fs:duration;
                        signal = (signal(1:length(n)))';
                    end
                    
            end
            
            switch widtype
                case '1'%rectangular
                    wind=ones(1,length(signal));
                case '2'%triangular
                    wind=triang(length(n));
                    wind=wind';
                case '3'%hanning
                    wind=hanning(length(n));
                    wind=wind';
                case '4'%hamming
                    wind=hamming(length(n));
                    wind=wind';
            end
            
            
            a=signal.*wind;
            signal=a;
            
            
        end
        %------------------------------------------------------------------------------
        %this function computes the signal and n to be plotted
        function [n1,signal1,n2,signal2] = compute_windowed_signal_comp(app)
            
            
            [F,Fs ,widlen1 ,widtype1 ,~ ,widlen2, widtype2 ,~,sigtype]= get_comp_data(app);
            
            % the first window----------------------------------------------------
            n1=0:1/Fs:widlen1;
            
            switch sigtype % this computes the signal according to the input choice
                case '1'%sin function
                    signal1=sin(2*pi*F.*n1);
                case '2'%sinc function
                    signal1=sinc(2*F.*n1);
                case '3'%Square function
                    signal1=square(2*pi*F.*n1);
            end
            
            switch widtype1
                case '1'%rectangular
                    wind1=ones(1,length(signal1));
                case '2'%triangular
                    wind1=triang(length(n1));
                    wind1=wind1';
                case '3'%hanning
                    wind1=hanning(length(n1));
                    wind1=wind1';
                case '4'%hamming
                    wind1=hamming(length(n1));
                    wind1=wind1';
            end
            
            signal1=signal1.*wind1;
            
            % the second window----------------------------------------------------
            n2=0:1/Fs:widlen2;
            
            switch sigtype % this computes the signal according to the input choice
                case '1'%sin function
                    signal2=sin(2*pi*F.*n2);
                case '2'%sinc function
                    signal2=sinc(2*F.*n2);
                case '3'%Square function
                    signal2=square(2*pi*F.*n2);
            end
            
            switch widtype2 % this computes the window according to the input choice
                case '1'%rectangular
                    wind2=ones(1,length(signal2));
                case '2'%triangular
                    wind2=triang(length(n2));
                    wind2=wind2';
                case '3'%hanning
                    wind2=hanning(length(n2));
                    wind2=wind2';
                case '4'%hamming
                    wind2=hamming(length(n2));
                    wind2=wind2';
            end
            signal2=signal2.*wind2;%multiply the widow with the signal
            
        end
        
        %---------------------------------------------------------------------------
        function  DFT_plotgraphs_main(app)
            clc;
            [~,~,~,~,NDFT,~,~,display]= get_main_data(app);
            [Fs,nt,signal] = compute_windowed_signal(app);
            fftsignal=fft(signal,str2double(NDFT));
            
            n=0:Fs/length(fftsignal):Fs-Fs/length(fftsignal);
            
            switch display
                
                case'1'% Magnitude (linear) and phase
                    
                    if(app.stem_plot_main.Value=='1')
                        
                        subplot(3,1,1);
                        datacursormode on;
                        stem(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,2);
                        datacursormode on;
                        stem(n,abs(fftsignal));
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        stem(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                    else
                        
                        subplot(3,1,1);
                        datacursormode on;
                        plot(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,2);
                        datacursormode on;
                        plot(n,abs(fftsignal));
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        plot(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                    end
                    
                    
                case'2' %magnetude (Db) and phase


                    if (app.stem_plot_main.Value=='1')
                        
                        subplot(3,1,1);
                        datacursormode on;
                        stem(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,2);
                        logfft=20*log10(fftsignal);
                        datacursormode on;
                        stem(n,logfft);
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude(Fb)')
                        
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        stem(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                    else
                        
                        subplot(3,1,1);
                        datacursormode on;
                        plot(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Ampl')
                        
                        subplot(3,1,2);
                        logfft=20*log10(fftsignal);
                        datacursormode on;
                        plot(n,logfft);
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl(Fb)')
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        plot(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                    end
                    
                    
                case'3'% Real and imaginary
                    if(app.stem_plot_main.Value=='1')
                        
                        subplot(3,1,1);
                        datacursormode on;
                        stem(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Ampl')
                        
                        subplot(3,1,2);
                        datacursormode on;
                        stem(n,real(fftsignal));
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,1,3);
                        datacursormode on;
                        stem(n,imag(fftsignal));
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                    else
                        subplot(3,1,1);
                        datacursormode on;
                        plot(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Ampl')
                        
                        
                        subplot(3,1,2);
                        datacursormode on;
                        plot(n,real(fftsignal));
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,1,3);
                        datacursormode on;
                        plot(n,imag(fftsignal));
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                    end
                    
            end
            
        end
        % -----------------------------------------------------------------
        %this function plots and comutes the FFt of the signals
        function  DFT_plotgraphs_comp(app)
            clc;
            [~,Fs ,~ ,~,NDFT1 ,~,~,NDFT2,~]= get_comp_data(app);
            
            [sn1,signal1,sn2,signal2] = compute_windowed_signal_comp(app);
            
            fftsignal1=fft(signal1,str2double(NDFT1));
            n1=0:Fs/length(fftsignal1):Fs-Fs/length(fftsignal1);
            
            fftsignal2=fft(signal2,str2double(NDFT2));
            n2=0:Fs/length(fftsignal2):Fs-Fs/length(fftsignal2);
            
            %             n1=0:length(fftsignal1)-1;
            
            if (app.Switch_2.Value=='1')
                subplot(3,2,1);
                datacursormode on;
                stem(sn1,signal1);
                title('Time Domain signal 1')
                xlabel('Time (s)')
                ylabel('Ampl')
                
                subplot(3,2,2);
                datacursormode on;
                stem(sn2,signal2,'r');
                title('Time Domain signal 2')
                xlabel('Time (s)')
                ylabel('Ampl')
                
                switch app.Displayoptions_comparison.Value
                    
                    case'1'% Magnitude (linear) and phase
                        
                        
                        subplot(3,2,3);
                        datacursormode on;
                        stem(n1,abs(fftsignal1));
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,2,5);
                        fftangle1 = unwrap(angle(fftsignal1));
                        datacursormode on;
                        stem(n1,fftangle1);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                        subplot(3,2,4);
                        datacursormode on;
                        stem(n2,abs(fftsignal2),'r');
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,2,6);
                        fftangle2 = unwrap(angle(fftsignal2));
                        datacursormode on;
                        stem(n2,fftangle2,'r');
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                    case'2' %magnetude (Db) and phase
                        
                        subplot(3,2,3);
                        logfft1=20*log10(fftsignal1);
                        datacursormode on;
                        stem(n1,logfft1);
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude(Fb)')
                        
                        
                        subplot(3,2,5);
                        fftangle1 = unwrap(angle(fftsignal1));
                        datacursormode on;
                        stem(n1,fftangle1);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                        subplot(3,2,4);
                        logfft2=20*log10(fftsignal2);
                        datacursormode on;
                        stem(n2,logfft2,'r');
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude(Fb)')
                        
                        
                        subplot(3,2,6);
                        fftangle2 = unwrap(angle(fftsignal2));
                        datacursormode on;
                        stem(n2,fftangle2,'r');
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')


                    case'3'% Real and imaginary
                        
                        subplot(3,2,3);
                        datacursormode on;
                        stem(n1,real(fftsignal1));
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,2,5);
                        datacursormode on;
                        stem(n1,imag(fftsignal1));
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,2,4);
                        datacursormode on;
                        stem(n2,real(fftsignal2),'r');
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,2,6);
                        datacursormode on;
                        stem(n2,imag(fftsignal2),'r');
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')


                end
                
            else
                subplot(3,2,1);
                datacursormode on;
                plot(sn1,signal1);
                title('Time Domain signal 1')
                xlabel('Time (s)')
                ylabel('Ampl')
                
                subplot(3,2,2);
                datacursormode on;
                plot(sn2,signal2,'r');
                title('Time Domain signal 2')
                xlabel('Time (s)')
                ylabel('Ampl')
                
                switch app.Displayoptions_comparison.Value
                    
                    case'1'% Magnitude (linear) and phase
                        
                        
                        subplot(3,2,3);
                        datacursormode on;
                        plot(n1,abs(fftsignal1));
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,2,5);
                        fftangle1 = unwrap(angle(fftsignal1));
                        datacursormode on;
                        plot(n1,fftangle1);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                        subplot(3,2,4);
                        datacursormode on;
                        plot(n2,abs(fftsignal2),'r');
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,2,6);
                        fftangle2 = unwrap(angle(fftsignal2));
                        datacursormode on;
                        plot(n2,fftangle2,'r');
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                    case'2' %magnetude (Db) and phase
                        
                        subplot(3,2,3);
                        logfft1=20*log10(fftsignal1);
                        datacursormode on;
                        plot(n1,logfft1);
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude(Fb)')
                        
                        
                        subplot(3,2,5);
                        fftangle1 = unwrap(angle(fftsignal1));
                        datacursormode on;
                        plot(n1,fftangle1);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                        subplot(3,2,4);
                        logfft2=20*log10(fftsignal2);
                        datacursormode on;
                        plot(n2,logfft2,'r');
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude(Fb)')
                        
                        
                        subplot(3,2,6);
                        fftangle2 = unwrap(angle(fftsignal2));
                        datacursormode on;
                        plot(n2,fftangle2,'r');
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')


                    case'3'% Real and imaginary
                        
                        subplot(3,2,3);
                        datacursormode on;
                        plot(n1,real(fftsignal1));
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,2,5);
                        datacursormode on;
                        plot(n1,imag(fftsignal1));
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,2,4);
                        datacursormode on;
                        plot(n2,real(fftsignal2),'r');
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,2,6);
                        datacursormode on;
                        plot(n2,imag(fftsignal2),'r');
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')


                end
                
            end


        end


        function [input,widtype,NDFT,widlen,sigtype,F,Fs,DC,display,begining,ending] = get_calc_data(app)
            
            input=app.InputFile_calculation.Value;% the path of the input file ("" if was not entred)
            
            widtype=app.WindowType_calculation.Value;%the type of the window 1->rect 2->triang 3->Hannig 4->hamming
            
            NDFT=app.NDFT_calculation.Value;% NDFt (does not work well if the number of samples is less thean the DFT)
            
            widlen=app.Windowlength_calculation.Value;% the window length
            
            sigtype=app.SignalType_main.Value;%the signal type
            
            F=app.SignalFrequnecy_calculation.Value;% the signal frequency
            
            Fs=app.SamplingFrequency_calculation.Value;% the sampling frequency
            
            DC=app.DC_component_calculation.Value;
            
            display=app.Displayoptions_calc.Value;%teh type of display
            %span
            begining=app.Begining_calculation.Value;
            ending=app.End_calculation.Value;
            
        end
        %----------------------------------------------------------------------------------------------------------------------------------------------------------------------
        function [Fs,n,signal] = compute_windowed_signal_calc(app)
            
            
            [input,widtype,~,widlen,sigtype,F,Fs,DC,~,~,~]= get_calc_data(app);
            
            n=0:1/Fs:widlen;
            
            sample_rate=Fs;% just to make sure that every thing goes well if the user did not enter a path of the input file
            if (app.input_choice.Value=='0')
                
                if(input==' ')
                    signal=sin(2*pi*F.*n)+DC;
                    f = app.SpectrumAnalyzerUIFigure;
                    uialert(f,'File not found','Invalid File');
                    
                else
                    [signal , sample_rate]= audioread(input);
                    Fs=sample_rate;% we by default take the sample rate of the signal
                    
                    app.SamplingFrequency_calculation_input.Value=Fs;
                    duration=floor(length(signal)/Fs);
                    app.SignalLength_calculation_input.Value=duration;
                    n=0:1/Fs:duration;
                    signal = (signal(1:length(n)))';
                    
                end
            else
                switch sigtype % this case is
                    case '1'
                        signal=sin(2*pi*F.*n)+DC;
                    case '2'
                        signal=sinc(2*F.*n)+DC;
                    case '3'
                        signal=square(2*pi*F.*n)+DC;
                end
                
            end
            
            
            switch widtype
                case '1'%rectangular
                    wind=ones(1,length(signal));
                case '2'%triangular
                    wind=triang(length(n));
                    wind=wind';
                case '3'%hanning
                    wind=hanning(length(n));
                    wind=wind';
                case '4'%hamming
                    wind=hamming(length(n));
                    wind=wind';
            end
            
            
            signal=signal.*wind;
            
        end
        %--------------------------------------------------------------------------------------------------------------------------------------------------------
        function  DFT_plotgraphs_calc(app)
            [~,~,NDFT,~,~,~,~,~,display,begining,ending] = get_calc_data(app);
            [Fs,nt,signal] = compute_windowed_signal_calc(app);
            
%             fftsignal=fft(signal,str2double(NDFT));
%             n=0:Fs/length(fftsignal):Fs-Fs/length(fftsignal);
            
            original_fftsignal=fft(signal,str2double(NDFT));
            
            N=length(original_fftsignal);%total length of the signal
            
            begind=floor(begining*N/Fs)+1;%index of the beginning frequency
            endind=floor(ending*N/Fs);%index of the ending frequency
            
            fftsignal=original_fftsignal(begind:endind);%to be modified
            
            n=begining:Fs/N:ending-Fs/N;%to be modified
            
            lenn=length(n);
            lenfft=length(fftsignal);
            
            if (lenfft-lenn==1)
               
                  n=begining:Fs/N:ending;  
            elseif (lenn-lenfft==1)
                 fftsignal=[fftsignal , fftsignal(ending+1)];
            end
         
            
            switch display
                
                case'1'% Magnitude (linear) and phase
                    
                    if(app.stem_plot_calculation.Value=='1')
                        
                        subplot(3,1,1);
                        datacursormode on;
                        stem(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,2);
                        datacursormode on;
                        stem(n,abs(fftsignal));
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        stem(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                    else
                        
                        subplot(3,1,1);
                        datacursormode on;
                        plot(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,2);
                        datacursormode on;
                        plot(n,abs(fftsignal));
                        title('Magnitude (linear)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        plot(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                    end
                    
                    
                case'2' %magnetude (Db) and phase


                    if (app.stem_plot_calculation.Value=='1')
                        
                        subplot(3,1,1);
                        datacursormode on;
                        stem(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Magnitude')
                        
                        subplot(3,1,2);
                        logfft=20*log10(fftsignal);
                        datacursormode on;
                        stem(n,logfft);
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Magnitude(Fb)')
                        
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        stem(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                        
                    else
                        
                        subplot(3,1,1);
                        datacursormode on;
                        plot(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Ampl')
                        
                        subplot(3,1,2);
                        logfft=20*log10(fftsignal);
                        datacursormode on;
                        plot(n,logfft);
                        title('Magnitude (Db)')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl(Fb)')
                        
                        subplot(3,1,3);
                        fftangle = unwrap(angle(fftsignal));
                        datacursormode on;
                        plot(n,fftangle);
                        title('phase')
                        xlabel('Frequency (Hz)')
                        ylabel('Angle(rad)')
                        
                    end
                    
                    
                case'3'% Real and imaginary
                    if(app.stem_plot_calculation.Value=='1')
                        
                        subplot(3,1,1);
                        datacursormode on;
                        stem(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Ampl')
                        
                        subplot(3,1,2);
                        datacursormode on;
                        stem(n,real(fftsignal));
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,1,3);
                        datacursormode on;
                        stem(n,imag(fftsignal));
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                    else
                        subplot(3,1,1);
                        datacursormode on;
                        plot(nt,signal);
                        title('Time Domain')
                        xlabel('Time (s)')
                        ylabel('Ampl')
                        
                        
                        subplot(3,1,2);
                        datacursormode on;
                        plot(n,real(fftsignal));
                        title('Real component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                        subplot(3,1,3);
                        datacursormode on;
                        plot(n,imag(fftsignal));
                        title('Imaginary component')
                        xlabel('Frequency (Hz)')
                        ylabel('Ampl')
                        
                    end
            end
        end
        
        function   calculations(app)
            
            [~,~,NDFT,~,~,~,~,~,display,begining,ending] = get_calc_data(app);
            [Fs,nt,signal] = compute_windowed_signal_calc(app);


            NDFT=str2double(NDFT);
            original_fftsignal=fft(signal);
            
            N=length(original_fftsignal);%total length of the signal
            
         
            rbw=Fs/NDFT;
            app.RBW.Value=rbw;


%             fftsignal=original_fftsignal(begind:endind);%to be modified
            
%             n=begining:Fs/length(fftsignal):endind-Fs/length(fftsignal);%to be modified
%           
            %get the DC component accross the whole signal
            
            DC=original_fftsignal(1)/(length(original_fftsignal));
            
            app.DCbias.Value=DC;
            if (abs(DC)<0.0001)
                app.Lamp.Enable=0;
            else
                app.Lamp.Enable=1;
            end
           %--------------------------------------------------- 
             original_fftsignal=fft(signal,NDFT);
            
            N=length(original_fftsignal);%total length of the signal
            
            begind=floor(begining*N/Fs)+1;%index of the beginning frequency
            endind=floor(ending*N/Fs);%index of the ending frequency
            
            fftsignal=original_fftsignal(begind:endind);%to be modified
            
            n=begining:Fs/N:ending-Fs/N;%to be modified
            
            lenn=length(n);
            lenfft=length(fftsignal);
            
            if (lenfft-lenn==1)
               
                  n=begining:Fs/N:ending;  
            elseif (lenn-lenfft==1)
                 fftsignal=[fftsignal , fftsignal(ending+1)];
            end
            
            
%               peak value
            [M,I] = max(abs(fftsignal));
            app.Value_peak.Value=M;
            
            
            % power calculation
            total_power=0;
            for i=1:length(fftsignal)
                total_power=total_power+(abs(fftsignal(i))*abs(fftsignal(i)));
            end
            total_power=total_power/length(fftsignal);
            app.PowerValueEditField.Value=total_power;
            app.RMS.Value=sqrt(total_power);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            %main mode
            app.Signalfrequency_main.Limits=[0,app.SamplingFrequency_main.Value/2];
            app.Windowlength_main.Limits=[0,str2double(app.NDFT_main.Value)];%in order to make windowing
            
            NDFT=str2double(app.NDFT_calculation.Value);
            app.Windowlength_calculation.Limits=[0,NDFT];%in order to make windowing
            
            app.SignalFrequnecy_calculation.Limits=[0,app.SamplingFrequency_calculation.Value/2];%because if the signal frequency is higher than fs/2 ,aliasing will happen


        end

        % Button pushed function: BrowseButton_main
        function BrowseButton_mainPushed(app, event)
            try %to handle the error if the user has canceld the browsing.and make sure that  app.InputFileEditField.Value has a valid input
                [file_name,path] = uigetfile({'*.wav'},'File Selector');
                full_path= [path file_name];
                app.InputFileEditField.Value=full_path;
            catch
                app.InputFileEditField.Value=' ';
            end
        end

        % Button pushed function: ComputeButton_main
        function ComputeButton_mainPushed(app, event)
            
            
            DFT_plotgraphs_main(app);
            
            
        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
            
            DFT_plotgraphs_comp(app);
            
            
        end

        % Button pushed function: MainmenuButton
        function MainmenuButtonPushed(app, event)
            choice=menu('all the cahanges you made will get lost !','OK','Cancel');
            if choice==1
                app.delete;
                Mainmenue;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            choice=menu('Are you sure you want to exit ?','yes','No');
            if choice==1
                app.delete;
            end
        end

        % Button pushed function: BrowseButton_calculation
        function BrowseButton_calculationPushed(app, event)
            try %to handle the error if the user has canceld the browsing.and make sure that  app.InputFile_calculation.Value has a valid input
                [file_name,path] = uigetfile({'*.wav'},'File Selector');
                full_path= [path file_name];
                app.InputFile_calculation.Value=full_path;
                compute_windowed_signal_calc(app);
            catch
                app.InputFile_calculation.Value=' ';
            end
        end

        % Button pushed function: compute_calculation
        function compute_calculationButtonPushed(app, event)
            DFT_plotgraphs_calc(app);
            calculations(app);
            
        end

        % Value changed function: input_choice
        function input_choiceValueChanged(app, event)
            value = app.input_choice.Value;
            if (value=='0') %input signal
                
                app.DC_component_calculation.Enable=0;
                app.SignalFrequnecy_calculation.Enable=0;
                app.SamplingFrequency_calculation.Enable=0;
                app.SignalType_calculation.Enable=0;
                
                app.InputFile_calculation.Enable=1;
                app.BrowseButton_calculation.Enable=1;
                app.SamplingFrequency_calculation_input.Enable=1;
                app.SignalLength_calculation_input.Enable=1;
                %                 app.Windowlength_calculation.Limits=[0,app.SignalLength_calculation_input.Value];
                
                %                 app.End_calculation.Limits=[app.Begining_calculation.Value,app.SamplingFrequency_calculation_input.Value];
                app.Begining_calculation.Limits=[0,app.End_calculation.Value];
                
            else
                app.InputFile_calculation.Enable=0;
                app.BrowseButton_calculation.Enable=0;
                app.SamplingFrequency_calculation_input.Enable=0;
                app.SignalLength_calculation_input.Enable=0;
                
                app.DC_component_calculation.Enable=1;
                app.SignalFrequnecy_calculation.Enable=1;
                app.SamplingFrequency_calculation.Enable=1;
                app.SignalType_calculation.Enable=1;
                
                app.Windowlength_calculation.Limits=[0,inf];
                app.End_calculation.Limits=[app.Begining_calculation.Value,app.SamplingFrequency_calculation.Value];
                app.Begining_calculation.Limits=[0,app.End_calculation.Value];
                
            end
        end

        % Value changed function: Windowlength_calculation
        function Windowlength_calculationValueChanged(app, event)
        
        end

        % Value changed function: NDFT_calculation
        function NDFT_calculationValueChanged(app, event)
            value = app.NDFT_calculation.Value;
            app.Windowlength_calculation.Limits=[0,str2double(value)];%to make sure that the window length will always be less than or equal to NDFT
        end

        % Value changed function: SamplingFrequency_calculation
        function SamplingFrequency_calculationValueChanged(app, event)
            fs = app.SamplingFrequency_calculation.Value;
            app.SignalFrequnecy_calculation.Limits=[0,fs/2];%because if the signal frequency is higher than fs/2 ,aliasing will happen
            app.Begining_calculation.Limits=[0,app.End_calculation.Value];
            app.End_calculation.Limits=[app.Begining_calculation.Value,fs];
        end

        % Value changed function: End_calculation
        function End_calculationValueChanged(app, event)
            value = app.End_calculation.Value;
            app.Begining_calculation.Limits=[0,value];% the beginining should never exceed the end
        end

        % Value changed function: Begining_calculation
        function Begining_calculationValueChanged(app, event)
            value = app.Begining_calculation.Value;
            if(app.input_choice.Value=='1')
                app.End_calculation.Limits=[value,app.SamplingFrequency_calculation.Value];% the end ranges from the beginning and sampling frequency
            else
                app.End_calculation.Limits=[value,app.SamplingFrequency_calculation_input.Value];% the end ranges from the beginning and the sampling frequency
            end
        end

        % Value changed function: SamplingFrequency_main
        function SamplingFrequency_mainValueChanged(app, event)
            fs = app.SamplingFrequency_main.Value;
            app.Signalfrequency_main.Limits=[0,fs/2];%because if the signal frequency is higher than fs/2 ,aliasing will happen
        end

        % Value changed function: NDFT_main
        function NDFT_mainValueChanged(app, event)
            NDFT= str2double(app.NDFT_main.Value);
            app.Windowlength_main.Limits=[0,NDFT];
        end

        % Value changed function: 
        % SamplingFrequencyEditField_calculation
        function SamplingFrequencyEditField_calculationValueChanged(app, event)
            Fs = app.SamplingFrequencyEditField_calculation.Value;
            app.SignalfrequencyEditField_calculation.Limits=[0,Fs/2];
        end

        % Value changed function: NDFT1DropDown
        function NDFT1DropDownValueChanged(app, event)
            value = str2double(app.NDFT1DropDown.Value);
            app.Window1lengthEditField.Limits=[0,value];
            
        end

        % Value changed function: Window2lengthEditField
        function Window2lengthEditFieldValueChanged(app, event)
            
        end

        % Value changed function: NDFT2DropDown
        function NDFT2DropDownValueChanged(app, event)
            value = str2double(app.NDFT2DropDown.Value);
            app.Window2lengthEditField.Limits=[0,value];
            
        end

        % Button pushed function: Reset_span
        function Reset_spanButtonPushed(app, event)
            app.Begining_calculation.Value=0;
            if (app.input_choice.Value=='0')
                value=app.SamplingFrequency_calculation_input.Value;
                app.End_calculation.Limits=[0,value];
                app.End_calculation.Value=value;
            else
                app.End_calculation.Limits=[0,app.SamplingFrequency_calculation.Value];
                app.End_calculation.Value=app.SamplingFrequency_calculation.Value;
                
            end
            
            
        end

        % Value changed function: SamplingFrequency_calculation_input
        function SamplingFrequency_calculation_inputValueChanged(app, event)
            value = app.SamplingFrequency_calculation_input.Value;
            
        end

        % Value changed function: SignalLength_calculation_input
        function SignalLength_calculation_inputValueChanged(app, event)
            value = app.SignalLength_calculation_input.Value;
            
        end

        % Value changed function: InputFile_calculation
        function InputFile_calculationValueChanged(app, event)
            app.Begining_calculation.Limits=[0,app.SamplingFrequency_calculation_input.Value];
            app.End_calculation.Limits=[app.Begining_calculation.Value,app.SamplingFrequency_calculation_input.Value];
            compute_windowed_signal_calc(app);
        end

        % Value changing function: InputFile_calculation
        function InputFile_calculationValueChanging(app, event)
            app.Begining_calculation.Limits=[0,app.SamplingFrequency_calculation_input.Value];
            app.End_calculation.Limits=[app.Begining_calculation.Value,app.SamplingFrequency_calculation_input.Value];
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrumAnalyzerUIFigure = uifigure;
            app.SpectrumAnalyzerUIFigure.Name = 'Spectrum Analyzer';
            app.TabGroup = uitabgroup(app.SpectrumAnalyzerUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main ';
            app.ComputeButton_main = uibutton(app.MainTab, 'push');
            app.ComputeButton_main.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_mainPushed, true);
            app.ComputeButton_main.Text = 'Compute';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.MainTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequency_main = uieditfield(app.MainTab, 'numeric');
            app.SamplingFrequency_main.LowerLimitInclusive = 'off';
            app.SamplingFrequency_main.Limits = [0 Inf];
            app.SamplingFrequency_main.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequency_mainValueChanged, true);
            app.SamplingFrequency_main.Value = 50;
            app.WindowlengthEditFieldLabel = uilabel(app.MainTab);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.Windowlength_main = uieditfield(app.MainTab, 'numeric');
            app.Windowlength_main.Limits = [0 Inf];
            app.Windowlength_main.Value = 5;
            app.SignalfrequencyEditFieldLabel = uilabel(app.MainTab);
            app.SignalfrequencyEditFieldLabel.Text = 'Signal frequency';
            app.Signalfrequency_main = uieditfield(app.MainTab, 'numeric');
            app.Signalfrequency_main.LowerLimitInclusive = 'off';
            app.Signalfrequency_main.Limits = [0 Inf];
            app.Signalfrequency_main.Value = 0.5;
            app.InputFileEditFieldLabel = uilabel(app.MainTab);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.MainTab, 'text');
            app.InputFileEditField.Editable = 'off';
            app.InputFileEditField.Value = ' ';
            app.BrowseButton_main = uibutton(app.MainTab, 'push');
            app.BrowseButton_main.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_mainPushed, true);
            app.BrowseButton_main.Text = 'Browse';
            app.SignalTypeDropDownLabel = uilabel(app.MainTab);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalType_main = uidropdown(app.MainTab);
            app.SignalType_main.Items = {'Sin', 'Sinc', 'Square', 'Input Signal'};
            app.SignalType_main.ItemsData = {'1', '2', '3', '4'};
            app.SignalType_main.Value = '1';
            app.NDFTDropDownLabel = uilabel(app.MainTab);
            app.NDFTDropDownLabel.Text = 'N-DFT';
            app.NDFT_main = uidropdown(app.MainTab);
            app.NDFT_main.Items = {'128', '256', '512', '65536'};
            app.NDFT_main.ItemsData = {'128', '256', '512', '65536'};
            app.NDFT_main.ValueChangedFcn = createCallbackFcn(app, @NDFT_mainValueChanged, true);
            app.NDFT_main.Value = '128';
            app.DisplayoptionsDropDownLabel = uilabel(app.MainTab);
            app.DisplayoptionsDropDownLabel.Text = 'Display options';
            app.Displayoptions_main = uidropdown(app.MainTab);
            app.Displayoptions_main.Items = {'Magnitude (linear) and phase', 'Magnitude (Db) and phase', 'Real and imaginary'};
            app.Displayoptions_main.ItemsData = {'1', '2', '3'};
            app.Displayoptions_main.Value = '1';
            app.WindowTypeDropDownLabel = uilabel(app.MainTab);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowType_main = uidropdown(app.MainTab);
            app.WindowType_main.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.WindowType_main.ItemsData = {'1', '2', '3', '4'};
            app.WindowType_main.Value = '1';
            app.stem_plot_main = uiswitch(app.MainTab, 'slider');
            app.stem_plot_main.Items = {'stem', 'plot'};
            app.stem_plot_main.ItemsData = {'1', '0'};
            app.stem_plot_main.Value = '1';
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.ComputeButton_2 = uibutton(app.ComparisonTab, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.Switch_2 = uiswitch(app.ComparisonTab, 'slider');
            app.Switch_2.Items = {'stem', 'plot'};
            app.Switch_2.ItemsData = {'1', '0'};
            app.Switch_2.Value = '1';
            app.GeneralsittingsPanel = uipanel(app.ComparisonTab);
            app.GeneralsittingsPanel.Title = 'General sittings';
            app.SamplingFrequencyEditField_2Label = uilabel(app.GeneralsittingsPanel);
            app.SamplingFrequencyEditField_2Label.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField_calculation = uieditfield(app.GeneralsittingsPanel, 'numeric');
            app.SamplingFrequencyEditField_calculation.Limits = [0 Inf];
            app.SamplingFrequencyEditField_calculation.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyEditField_calculationValueChanged, true);
            app.SamplingFrequencyEditField_calculation.Value = 50;
            app.SignalfrequencyEditField_2Label = uilabel(app.GeneralsittingsPanel);
            app.SignalfrequencyEditField_2Label.Text = 'Signal frequency';
            app.SignalfrequencyEditField_calculation = uieditfield(app.GeneralsittingsPanel, 'numeric');
            app.SignalfrequencyEditField_calculation.UpperLimitInclusive = 'off';
            app.SignalfrequencyEditField_calculation.Limits = [0 25];
            app.SignalfrequencyEditField_calculation.Value = 0.5;
            app.SignalTypeDropDown_2Label = uilabel(app.GeneralsittingsPanel);
            app.SignalTypeDropDown_2Label.Text = 'Signal Type';
            app.SignalTypeDropDown_calculation = uidropdown(app.GeneralsittingsPanel);
            app.SignalTypeDropDown_calculation.Items = {'Sin', 'Sinc', 'Square', ''};
            app.SignalTypeDropDown_calculation.ItemsData = {'1', '2', '3', '4'};
            app.SignalTypeDropDown_calculation.Value = '1';
            app.DisplayoptionsDropDown_2Label = uilabel(app.GeneralsittingsPanel);
            app.DisplayoptionsDropDown_2Label.Text = 'Display options';
            app.Displayoptions_comparison = uidropdown(app.GeneralsittingsPanel);
            app.Displayoptions_comparison.Items = {'Magnitude (linear) and phase', 'Magnitude (Db) and phase', 'Real and imaginary'};
            app.Displayoptions_comparison.ItemsData = {'1', '2', '3'};
            app.Displayoptions_comparison.Value = '1';
            app.Window1Panel = uipanel(app.ComparisonTab);
            app.Window1Panel.Title = 'Window1';
            app.NDFT1DropDownLabel = uilabel(app.Window1Panel);
            app.NDFT1DropDownLabel.Text = 'N-DFT 1';
            app.NDFT1DropDown = uidropdown(app.Window1Panel);
            app.NDFT1DropDown.Items = {'128', '256', '512', '65536'};
            app.NDFT1DropDown.ItemsData = {'128', '256', '512', '65536'};
            app.NDFT1DropDown.ValueChangedFcn = createCallbackFcn(app, @NDFT1DropDownValueChanged, true);
            app.NDFT1DropDown.Value = '128';
            app.Window1TypeDropDownLabel = uilabel(app.Window1Panel);
            app.Window1TypeDropDownLabel.Text = 'Window 1 Type';
            app.Window1TypeDropDown = uidropdown(app.Window1Panel);
            app.Window1TypeDropDown.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.Window1TypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.Window1TypeDropDown.Value = '1';
            app.Window1lengthEditFieldLabel = uilabel(app.Window1Panel);
            app.Window1lengthEditFieldLabel.Text = 'Window 1 length';
            app.Window1lengthEditField = uieditfield(app.Window1Panel, 'numeric');
            app.Window1lengthEditField.Limits = [0 128];
            app.Window1lengthEditField.Value = 5;
            app.Window2Panel = uipanel(app.ComparisonTab);
            app.Window2Panel.Title = 'Window 2';
            app.Window2lengthEditFieldLabel = uilabel(app.Window2Panel);
            app.Window2lengthEditFieldLabel.Text = 'Window 2 length';
            app.Window2lengthEditField = uieditfield(app.Window2Panel, 'numeric');
            app.Window2lengthEditField.Limits = [0 128];
            app.Window2lengthEditField.ValueChangedFcn = createCallbackFcn(app, @Window2lengthEditFieldValueChanged, true);
            app.Window2lengthEditField.Value = 5;
            app.Window2TypeDropDownLabel = uilabel(app.Window2Panel);
            app.Window2TypeDropDownLabel.Text = 'Window 2 Type';
            app.Window2TypeDropDown = uidropdown(app.Window2Panel);
            app.Window2TypeDropDown.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.Window2TypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.Window2TypeDropDown.Value = '1';
            app.NDFT2DropDownLabel = uilabel(app.Window2Panel);
            app.NDFT2DropDownLabel.Text = 'N-DFT 2';
            app.NDFT2DropDown = uidropdown(app.Window2Panel);
            app.NDFT2DropDown.Items = {'128', '256', '512', '65536'};
            app.NDFT2DropDown.ItemsData = {'128', '256', '512', '65536'};
            app.NDFT2DropDown.ValueChangedFcn = createCallbackFcn(app, @NDFT2DropDownValueChanged, true);
            app.NDFT2DropDown.Value = '128';
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.compute_calculation = uibutton(app.CalculationTab, 'push');
            app.compute_calculation.ButtonPushedFcn = createCallbackFcn(app, @compute_calculationButtonPushed, true);
            app.compute_calculation.Text = 'Compute';
            app.InputFileEditField_2Label = uilabel(app.CalculationTab);
            app.InputFileEditField_2Label.Text = 'Input File';
            app.InputFile_calculation = uieditfield(app.CalculationTab, 'text');
            app.InputFile_calculation.ValueChangedFcn = createCallbackFcn(app, @InputFile_calculationValueChanged, true);
            app.InputFile_calculation.ValueChangingFcn = createCallbackFcn(app, @InputFile_calculationValueChanging, true);
            app.InputFile_calculation.Editable = 'off';
            app.InputFile_calculation.Enable = 'off';
            app.InputFile_calculation.Value = ' ';
            app.BrowseButton_calculation = uibutton(app.CalculationTab, 'push');
            app.BrowseButton_calculation.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_calculationPushed, true);
            app.BrowseButton_calculation.Enable = 'off';
            app.BrowseButton_calculation.Text = 'Browse';
            app.GeneratesignalPanel = uipanel(app.CalculationTab);
            app.GeneratesignalPanel.Title = 'Generate signal';
            app.DCcomponentEditFieldLabel = uilabel(app.GeneratesignalPanel);
            app.DCcomponentEditFieldLabel.Text = 'DC component';
            app.DC_component_calculation = uieditfield(app.GeneratesignalPanel, 'numeric');
            app.DC_component_calculation.Value = 5;
            app.SignalFrequencyLabel = uilabel(app.GeneratesignalPanel);
            app.SignalFrequencyLabel.Text = 'Signal Frequency';
            app.SignalFrequnecy_calculation = uieditfield(app.GeneratesignalPanel, 'numeric');
            app.SignalFrequnecy_calculation.UpperLimitInclusive = 'off';
            app.SignalFrequnecy_calculation.Limits = [0 100];
            app.SignalFrequnecy_calculation.Value = 5;
            app.SamplingFrequencyEditField_3Label = uilabel(app.GeneratesignalPanel);
            app.SamplingFrequencyEditField_3Label.Text = 'Sampling Frequency';
            app.SamplingFrequency_calculation = uieditfield(app.GeneratesignalPanel, 'numeric');
            app.SamplingFrequency_calculation.Limits = [0 Inf];
            app.SamplingFrequency_calculation.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequency_calculationValueChanged, true);
            app.SamplingFrequency_calculation.Value = 100;
            app.SignalTypeDropDown_3Label = uilabel(app.GeneratesignalPanel);
            app.SignalTypeDropDown_3Label.Text = 'Signal Type';
            app.SignalType_calculation = uidropdown(app.GeneratesignalPanel);
            app.SignalType_calculation.Items = {'Sin', 'Sinc', 'Square'};
            app.SignalType_calculation.ItemsData = {'1', '2', '3'};
            app.SignalType_calculation.Value = '1';
            app.CommonsittingsPanel = uipanel(app.CalculationTab);
            app.CommonsittingsPanel.Title = 'Common sittings';
            app.WindowTypeLabel = uilabel(app.CommonsittingsPanel);
            app.WindowTypeLabel.Text = 'Window Type';
            app.WindowType_calculation = uidropdown(app.CommonsittingsPanel);
            app.WindowType_calculation.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.WindowType_calculation.ItemsData = {'1', '2', '3', '4'};
            app.WindowType_calculation.Value = '1';
            app.NDFTLabel = uilabel(app.CommonsittingsPanel);
            app.NDFTLabel.Text = 'N-DFT ';
            app.NDFT_calculation = uidropdown(app.CommonsittingsPanel);
            app.NDFT_calculation.Items = {'128', '256', '512', '65536'};
            app.NDFT_calculation.ItemsData = {'128', '256', '512', '65536'};
            app.NDFT_calculation.ValueChangedFcn = createCallbackFcn(app, @NDFT_calculationValueChanged, true);
            app.NDFT_calculation.Value = '128';
            app.WindowlengthLabel = uilabel(app.CommonsittingsPanel);
            app.WindowlengthLabel.Text = 'Window  length';
            app.Windowlength_calculation = uieditfield(app.CommonsittingsPanel, 'numeric');
            app.Windowlength_calculation.Limits = [0 128];
            app.Windowlength_calculation.ValueChangedFcn = createCallbackFcn(app, @Windowlength_calculationValueChanged, true);
            app.Windowlength_calculation.Value = 5;
            app.SpanPanel = uipanel(app.CalculationTab);
            app.SpanPanel.Title = 'Span';
            app.BeginingHzLabel = uilabel(app.SpanPanel);
            app.BeginingHzLabel.Text = 'Begining(Hz)';
            app.Begining_calculation = uieditfield(app.SpanPanel, 'numeric');
            app.Begining_calculation.Limits = [0 Inf];
            app.Begining_calculation.RoundFractionalValues = 'on';
            app.Begining_calculation.ValueChangedFcn = createCallbackFcn(app, @Begining_calculationValueChanged, true);
            app.EndHzLabel = uilabel(app.SpanPanel);
            app.EndHzLabel.Text = 'End(Hz)';
            app.End_calculation = uieditfield(app.SpanPanel, 'numeric');
            app.End_calculation.Limits = [0 100];
            app.End_calculation.RoundFractionalValues = 'on';
            app.End_calculation.ValueChangedFcn = createCallbackFcn(app, @End_calculationValueChanged, true);
            app.End_calculation.Value = 5;
            app.Reset_span = uibutton(app.SpanPanel, 'push');
            app.Reset_span.ButtonPushedFcn = createCallbackFcn(app, @Reset_spanButtonPushed, true);
            app.Reset_span.Text = 'Reset span';
            app.input_choice = uiswitch(app.CalculationTab, 'slider');
            app.input_choice.Items = {'Input signal', 'Generate signal'};
            app.input_choice.ItemsData = {'0', '1'};
            app.input_choice.ValueChangedFcn = createCallbackFcn(app, @input_choiceValueChanged, true);
            app.input_choice.Value = '1';
            app.AudioinfoPanel = uipanel(app.CalculationTab);
            app.AudioinfoPanel.Title = 'ِAudio info';
            app.SamplingFrequencyEditField_3Label_2 = uilabel(app.AudioinfoPanel);
            app.SamplingFrequencyEditField_3Label_2.Text = 'Sampling Frequency';
            app.SamplingFrequency_calculation_input = uieditfield(app.AudioinfoPanel, 'numeric');
            app.SamplingFrequency_calculation_input.Limits = [0 Inf];
            app.SamplingFrequency_calculation_input.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequency_calculation_inputValueChanged, true);
            app.SamplingFrequency_calculation_input.Editable = 'off';
            app.SamplingFrequency_calculation_input.Enable = 'off';
            app.SamplingFrequency_calculation_input.Value = 100;
            app.SignaldurationLabel = uilabel(app.AudioinfoPanel);
            app.SignaldurationLabel.Text = 'Signal duration';
            app.SignalLength_calculation_input = uieditfield(app.AudioinfoPanel, 'numeric');
            app.SignalLength_calculation_input.Limits = [0 Inf];
            app.SignalLength_calculation_input.ValueChangedFcn = createCallbackFcn(app, @SignalLength_calculation_inputValueChanged, true);
            app.SignalLength_calculation_input.Editable = 'off';
            app.SignalLength_calculation_input.Enable = 'off';
            app.SignalLength_calculation_input.Value = 100;
            app.stem_plot_calculation = uiswitch(app.CalculationTab, 'slider');
            app.stem_plot_calculation.Items = {'Stem', 'Plot'};
            app.stem_plot_calculation.ItemsData = {'1', '0'};
            app.stem_plot_calculation.Value = '1';
            app.DisplayoptionsDropDown_4Label = uilabel(app.CalculationTab);
            app.DisplayoptionsDropDown_4Label.Text = 'Display options';
            app.Displayoptions_calc = uidropdown(app.CalculationTab);
            app.Displayoptions_calc.Items = {'Magnitude (linear) and phase', 'Magnitude (Db) and phase', 'Real and imaginary'};
            app.Displayoptions_calc.ItemsData = {'1', '2', '3'};
            app.Displayoptions_calc.Value = '1';
            app.signalInfoPanel = uipanel(app.CalculationTab);
            app.signalInfoPanel.Title = 'signal Info';
            app.PeakvalueLabel = uilabel(app.signalInfoPanel);
            app.PeakvalueLabel.Text = 'Peak value';
            app.Value_peak = uieditfield(app.signalInfoPanel, 'numeric');
            app.Value_peak.Editable = 'off';
            app.PowerValueEditFieldLabel = uilabel(app.signalInfoPanel);
            app.PowerValueEditFieldLabel.Text = 'Power Value';
            app.PowerValueEditField = uieditfield(app.signalInfoPanel, 'numeric');
            app.PowerValueEditField.Limits = [0 Inf];
            app.PowerValueEditField.Editable = 'off';
            app.RMSEditFieldLabel = uilabel(app.signalInfoPanel);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMS = uieditfield(app.signalInfoPanel, 'numeric');
            app.RMS.Limits = [0 Inf];
            app.RMS.Editable = 'off';
            app.Lamp = uilamp(app.signalInfoPanel);
            app.Lamp.Enable = 'off';
            app.DCbiasEditFieldLabel = uilabel(app.signalInfoPanel);
            app.DCbiasEditFieldLabel.Text = 'DC bias';
            app.DCbias = uieditfield(app.signalInfoPanel, 'numeric');
            app.DCbias.Editable = 'off';
            app.RBWLabel = uilabel(app.signalInfoPanel);
            app.RBWLabel.Text = 'RBW';
            app.RBW = uieditfield(app.signalInfoPanel, 'numeric');
            app.RBW.Limits = [0 Inf];
            app.RBW.Editable = 'off';
            app.MainmenuButton = uibutton(app.SpectrumAnalyzerUIFigure, 'push');
            app.MainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainmenuButtonPushed, true);
            app.MainmenuButton.Text = 'Main menu';
            app.ExitButton = uibutton(app.SpectrumAnalyzerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Spectrum

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrumAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrumAnalyzerUIFigure)
        end
    end
end